classdef challengeTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SupportSpecialistCheckBox      matlab.ui.control.CheckBox
        TankSpecialistCheckBox         matlab.ui.control.CheckBox
        AssassinSpecialistCheckBox     matlab.ui.control.CheckBox
        MageSpecialistCheckBox         matlab.ui.control.CheckBox
        FighterSpecialistCheckBox      matlab.ui.control.CheckBox
        MasterSupportCheckBox          matlab.ui.control.CheckBox
        MasterTankCheckBox             matlab.ui.control.CheckBox
        MasterAssassinCheckBox         matlab.ui.control.CheckBox
        ChallengesuniquetospecificrolesLabel  matlab.ui.control.Label
        MasterMageCheckBox             matlab.ui.control.CheckBox
        MasterFighterCheckBox          matlab.ui.control.CheckBox
        RoleLabel                      matlab.ui.control.Label
        Role                           matlab.ui.control.Label
        AllRandomAllChampionsCheckBox  matlab.ui.control.CheckBox
        HoldThatPoseCheckBox           matlab.ui.control.CheckBox
        InvincibleCheckBox             matlab.ui.control.CheckBox
        SamePentaDifferentChampCheckBox  matlab.ui.control.CheckBox
        PerfectionistCheckBox          matlab.ui.control.CheckBox
        ProteanOverrideCheckBox        matlab.ui.control.CheckBox
        ChampionNameDropDown           matlab.ui.control.DropDown
        ChampionDropDownLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("champions")
            warning('off','MATLAB:load:variableNotFound');
            if exist("challengeData.mat","file")
                % Calling this method to populate the values of the check
                % boxes.
                ChampionNameDropDownValueChanged(app);
            else
                % If the mat file does not exist, create it as an empty
                % file. It will be filled in as user selects champions in
                % the app.
                emptyStruct = struct();
                save('challengeData.mat','-struct','emptyStruct');
            end
        end

        % Value changed function: ChampionNameDropDown
        function ChampionNameDropDownValueChanged(app, event)
            value = app.ChampionNameDropDown.Value;
            objName = value + "Obj";
            % If you change the selection, make sure the champion class
            % object is loaded.
            load("challengeData.mat",objName);
            % Set the values of the check boxes to match the values in the
            % class object
            if ~exist(objName,"var")
                eval(objName + " = " + value + ";");
            end
            app.Role.Text = eval(objName+".role");
            app.ProteanOverrideCheckBox.Value = eval(objName+".protean");
            app.PerfectionistCheckBox.Value = eval(objName + ".perfectionist");
            app.SamePentaDifferentChampCheckBox.Value = eval(objName + ".penta");
            app.InvincibleCheckBox.Value = eval(objName + ".invincible");
            app.AllRandomAllChampionsCheckBox.Value = eval(objName + ".allRandomAllChampions");
            % Hold that pose is relevant only for some champions. Need
            % logic to show or hide this check box as needed?
            %app.HoldThatPoseCheckBox.Value = eval(objName + ".holdPose");

            % Fighter
            if find(strcmp(eval(objName + ".role"),"fighter"))
                app.MasterFighterCheckBox.Visible = true;
                app.MasterFighterCheckBox.Value = eval(objName + ".masterFighter");
            else
                app.MasterFighterCheckBox.Visible = false;
            end

            % Assassin
            if find(strcmp(eval(objName + ".role"),"assassin"))
            else
            end

            % Marksman
            if find(strcmp(eval(objName + ".role"),"marksman"))
            else
            end

            % Tank
            if find(strcmp(eval(objName + ".role"),"tank"))
            else
            end

            % Mage
            if find(strcmp(eval(objName + ".role"),"mage"))
                app.MasterMageCheckBox.Visible = true;
                app.MasterMageCheckBox.Value = eval(objName + ".masterMage");
            else
                app.MasterMageCheckBox.Visible = false;
            end

            % Support
            if find(strcmp(eval(objName + ".role"),"support"))
            else
            end

            % 
        end

        % Value changed function: ProteanOverrideCheckBox
        function ProteanOverrideCheckBoxValueChanged(app, event)
            objName = app.ChampionNameDropDown.Value + "Obj";
            load("challengeData.mat",objName);
            value = app.ProteanOverrideCheckBox.Value;
            eval(objName + ".protean" + " = " + string(value) + ";");
            save("challengeData.mat",objName);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            warning('on','MATLAB:load:variableNotFound')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChampionDropDownLabel = uilabel(app.UIFigure);
            app.ChampionDropDownLabel.Text = 'Champion';
            app.ChampionNameDropDown = uidropdown(app.UIFigure);
            app.ChampionNameDropDown.Items = {'Aatrox', 'Ahri', 'Akali', 'Akshan', 'Alistar', 'Ambessa', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Aurora', 'Azir', 'Bard', 'BelVeth', 'Blitzcrank', 'Brand', 'Braum', 'Briar', 'Caitlyn', 'Camille', 'Cassiopeia', 'ChoGath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Gwen', 'Hecarim', 'Heimerdinger', 'Hwei', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'KSante', 'KaiSa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'KhaZix', 'Kindred', 'Kled', 'KogMaw', 'LeBlanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'Mel', 'Milio', 'MissFortune', 'Mordekaiser', 'Morgana', 'Naafiri', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nilah', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'RenataGlasc', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Smolder', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'VelKoz', 'Vex', 'Vi', 'Viego', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'Wukong', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Zeri', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionNameDropDown.Editable = 'on';
            app.ChampionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChampionNameDropDownValueChanged, true);
            app.ChampionNameDropDown.Placeholder = 'Aatrox';
            app.ChampionNameDropDown.Value = 'Ahri';
            app.ProteanOverrideCheckBox = uicheckbox(app.UIFigure);
            app.ProteanOverrideCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProteanOverrideCheckBoxValueChanged, true);
            app.ProteanOverrideCheckBox.Text = 'Protean Override (Coop vs. AI wins)';
            app.PerfectionistCheckBox = uicheckbox(app.UIFigure);
            app.PerfectionistCheckBox.Text = 'Perfectionist (S+ grades)';
            app.SamePentaDifferentChampCheckBox = uicheckbox(app.UIFigure);
            app.SamePentaDifferentChampCheckBox.Text = 'Same Penta, Different Champ';
            app.InvincibleCheckBox = uicheckbox(app.UIFigure);
            app.InvincibleCheckBox.Text = 'Invincible (win without dying)';
            app.HoldThatPoseCheckBox = uicheckbox(app.UIFigure);
            app.HoldThatPoseCheckBox.Text = 'Hold That Pose (win 5-stack w/ 3 champs that have 2+ immobilizing spells)';
            app.AllRandomAllChampionsCheckBox = uicheckbox(app.UIFigure);
            app.AllRandomAllChampionsCheckBox.Text = 'All Random All Champions (S- on different champions in ARAM)';
            app.Role = uilabel(app.UIFigure);
            app.Role.Text = '';
            app.RoleLabel = uilabel(app.UIFigure);
            app.RoleLabel.Text = 'Role:';
            app.MasterFighterCheckBox = uicheckbox(app.UIFigure);
            app.MasterFighterCheckBox.Visible = 'off';
            app.MasterFighterCheckBox.Text = 'Master Fighter';
            app.MasterMageCheckBox = uicheckbox(app.UIFigure);
            app.MasterMageCheckBox.Visible = 'off';
            app.MasterMageCheckBox.Text = 'Master Mage';
            app.ChallengesuniquetospecificrolesLabel = uilabel(app.UIFigure);
            app.ChallengesuniquetospecificrolesLabel.Text = 'Challenges unique to specific roles:';
            app.MasterAssassinCheckBox = uicheckbox(app.UIFigure);
            app.MasterAssassinCheckBox.Visible = 'off';
            app.MasterAssassinCheckBox.Text = 'Master Assassin';
            app.MasterTankCheckBox = uicheckbox(app.UIFigure);
            app.MasterTankCheckBox.Visible = 'off';
            app.MasterTankCheckBox.Text = 'Master Tank';
            app.MasterSupportCheckBox = uicheckbox(app.UIFigure);
            app.MasterSupportCheckBox.Visible = 'off';
            app.MasterSupportCheckBox.Text = 'Master Support';
            app.FighterSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.FighterSpecialistCheckBox.Visible = 'off';
            app.FighterSpecialistCheckBox.Text = 'Fighter Specialist';
            app.MageSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.MageSpecialistCheckBox.Visible = 'off';
            app.MageSpecialistCheckBox.Text = 'Mage Specialist';
            app.AssassinSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.AssassinSpecialistCheckBox.Visible = 'off';
            app.AssassinSpecialistCheckBox.Text = 'Assassin Specialist';
            app.TankSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.TankSpecialistCheckBox.Visible = 'off';
            app.TankSpecialistCheckBox.Text = 'Tank Specialist';
            app.SupportSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.SupportSpecialistCheckBox.Visible = 'off';
            app.SupportSpecialistCheckBox.Text = 'Support Specialist';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = challengeTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end